classdef tic_tac_toe < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        FileMenu            matlab.ui.container.Menu
        saveMenu            matlab.ui.container.Menu
        closeMenu           matlab.ui.container.Menu
        helpMenu            matlab.ui.container.Menu
        knowmoreonlineMenu  matlab.ui.container.Menu
        promptLabel         matlab.ui.control.Label
        PLAYButton          matlab.ui.control.Button
        CRISSCROSSLabel     matlab.ui.control.Label
        Switch              matlab.ui.control.Switch
        Panel               matlab.ui.container.Panel
        GridLayout          matlab.ui.container.GridLayout
        Button_9            matlab.ui.control.Button
        Button_8            matlab.ui.control.Button
        Button_7            matlab.ui.control.Button
        Button_6            matlab.ui.control.Button
        Button_5            matlab.ui.control.Button
        Button_4            matlab.ui.control.Button
        Button_3            matlab.ui.control.Button
        Button_2            matlab.ui.control.Button
        Button              matlab.ui.control.Button
    end

    
    properties (Access = private)
    end
    
    properties (Access = public)
        p1_move % 1 for player 1 and 2 for player 2
        mat
    end


    methods (Access = public)
        
        function winlogic(app,m)
            % check horizontal win condition
            if (m(1,1) == m(1,2)) && (m(1,2) == m(1,3)) && (m(1,3) ~= -1)
                result = m(1,1);
                app.Button.BackgroundColor = 'g';
                app.Button_2.BackgroundColor = 'g';
                app.Button_3.BackgroundColor = 'g';
            elseif (m(2,1) == m(2,2)) && (m(2,2) == m(2,3)) && (m(2,3) ~= -1)
                result = m(2,1);
                app.Button_4.BackgroundColor = 'g';
                app.Button_5.BackgroundColor = 'g';
                app.Button_6.BackgroundColor = 'g';
            elseif (m(3,1) == m(3,2)) && (m(3,2) == m(3,3)) && (m(3,3) ~= -1)
                result = m(3,1);
                app.Button_7.BackgroundColor = 'g';
                app.Button_8.BackgroundColor = 'g';
                app.Button_9.BackgroundColor = 'g';

            elseif (m(1,1) == m(2,1)) && (m(2,1) == m(3,1)) && (m(3,1) ~= -1)
                result = m(1,1);
                app.Button.BackgroundColor = 'g';
                app.Button_4.BackgroundColor = 'g';
                app.Button_7.BackgroundColor = 'g';
                
            elseif (m(1,2) == m(2,2)) && (m(2,2) == m(3,2)) && (m(2,2) ~= -1)
                result = m(2,2);
                app.Button_2.BackgroundColor = 'g';
                app.Button_5.BackgroundColor = 'g';
                app.Button_8.BackgroundColor = 'g';
            elseif (m(1,3) == m(2,3)) && (m(2,3) == m(3,3)) && (m(3,3) ~= -1)
                result = m(3,3);
                app.Button_3.BackgroundColor = 'g';
                app.Button_6.BackgroundColor = 'g';
                app.Button_9.BackgroundColor = 'g';
            elseif (m(1,1) == m(2,2)) && (m(2,2) == m(3,3)) && (m(2,2) ~= -1)
                result = m(2,2);
                app.Button.BackgroundColor = 'g';
                app.Button_5.BackgroundColor = 'g';
                app.Button_9.BackgroundColor = 'g';
            elseif (m(1,3) == m(2,2)) && (m(2,2) == m(3,1)) && (m(2,2) ~= -1)
                result = m(2,2);
                app.Button_3.BackgroundColor = 'g';
                app.Button_5.BackgroundColor = 'g';
                app.Button_7.BackgroundColor = 'g';
            elseif ~ismember(m,-1)
                result = -1;
            else
                result = 0;
            end
            
            if result == 1
                app.promptLabel.Text = 'PLAYER 1 WON!';
            elseif result == 2
                app.promptLabel.Text = 'PLAYER 2 WON!';
            elseif result == -1
                app.promptLabel.Text = 'Game Tied!';
        end
    end
    end 

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function DisplayButtonPushed(app, event)
            app.MassinKgsEditField.Value = 'Hello World';
        end

        % Callback function
        function CalculateButtonPushed(app, event)
            mass = app.MassEditField.Value;
            acc = app.AccEditField.Value;
            Force = mass * acc;
            app.ForceEditField.Value = Force;

            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button.Text = 'O';
                app.mat(1,1) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button.Text = 'X';
                app.mat(1,1) = 2;
            end
           app.Button.Enable = 'off';
           winlogic(app,app.mat); 
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_2.Text = 'O';
                app.mat(1,2) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_2.Text = 'X';
                app.mat(1,2) = 2;
            end
            app.Button_2.Enable = 'off';
             winlogic(app,app.mat);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if strcmp(value, 'Dark')
                app.UIFigure.Color = 'k';
                app.promptLabel.FontColor = 'w';
            else if strcmp(value, 'Light')
                 app.UIFigure.Color = 'w';
                 app.promptLabel.FontColor = 'k';
            end
            end
        end

        % Callback function
        function Button222Pushed(app, event)
            
        end

        % Button pushed function: PLAYButton
        function PLAYButtonPushed(app, event)
            app.promptLabel.Text = 'Click the play  button'
            app.Button.Enable = 'on';
            app.Button_2.Enable= 'on';
            app.Button_3.Enable= 'on';
            app.Button_4.Enable= 'on';
            app.Button_5.Enable= 'on';
            app.Button_6.Enable= 'on';
            app.Button_7.Enable= 'on';
            app.Button_8.Enable= 'on';
            app.Button_9.Enable= 'on';

            app.Button.BackgroundColor = 'w';
            app.Button_2.BackgroundColor= 'w';
            app.Button_3.BackgroundColor= 'w';
            app.Button_4.BackgroundColor= 'w';
            app.Button_5.BackgroundColor= 'w';
            app.Button_6.BackgroundColor= 'w';
            app.Button_7.BackgroundColor= 'w';
            app.Button_8.BackgroundColor= 'w';
            app.Button_9.BackgroundColor= 'w';

            app.Button.Text = '';
            app.Button_2.Text= '';
            app.Button_3.Text= '';
            app.Button_4.Text= '';
            app.Button_5.Text= '';
            app.Button_6.Text= '';
            app.Button_7.Text= '';
            app.Button_8.Text= '';
            app.Button_9.Text= '';
            app.p1_move = 1;
            app.mat = -1 * ones(3,3);
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_3.Text = 'O';
                app.mat(1,3) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_3.Text = 'X';
                app.mat(1,3) = 2;
            end
           app.Button_3.Enable = 'off';
           winlogic(app,app.mat);
        end

        % Button pushed function: Button_4
        function Button_4Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_4.Text = 'O';
                app.mat(2,1) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_4.Text = 'X';
                app.mat(2,1) = 2;
            end
           app.Button_4.Enable = 'off';
           winlogic(app,app.mat);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_5.Text = 'O';
                app.mat(2,2) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_5.Text = 'X';
                app.mat(2,2) = 2;
            end
           app.Button_5.Enable = 'off';
           winlogic(app,app.mat);
        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_6.Text = 'O';
                app.mat(2,3) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_6.Text = 'X';
                app.mat(2,3) = 2;
            end
           app.Button_6.Enable = 'off';
           winlogic(app,app.mat);
        end

        % Button pushed function: Button_7
        function Button_7Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_7.Text = 'O';
                app.mat(3,1) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_7.Text = 'X';
                app.mat(3,1) = 2;
            end
           app.Button_7.Enable = 'off';
            winlogic(app,app.mat);
        end

        % Button pushed function: Button_8
        function Button_8Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_8.Text = 'O';
                app.mat(3,2) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_8.Text = 'X';
                app.mat(3,2) = 2;
            end
           app.Button_8.Enable = 'off';
           winlogic(app,app.mat);
        end

        % Button pushed function: Button_9
        function Button_9Pushed(app, event)
            if app.p1_move == 1
                app.promptLabel.Text = 'Now its turn of player 2';
                app.p1_move = 2;
                app.Button_9.Text = 'O';
                app.mat(3,3) = 1;
            else
                app.promptLabel.Text = 'Now its turn of player 1';
                app.p1_move = 1;
                app.Button_9.Text = 'X';
                app.mat(3,3) = 2;
            end
           app.Button_9.Enable = 'off';
           winlogic(app,app.mat);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.saveMenu = uimenu(app.FileMenu);
            app.saveMenu.Text = 'save';
            app.closeMenu = uimenu(app.FileMenu);
            app.closeMenu.Text = 'close';
            app.helpMenu = uimenu(app.UIFigure);
            app.helpMenu.Text = 'help';
            app.knowmoreonlineMenu = uimenu(app.helpMenu);
            app.knowmoreonlineMenu.Text = 'know more online';
            app.Panel = uipanel(app.UIFigure);
            app.GridLayout = uigridlayout(app.Panel);
            app.Button = uibutton(app.GridLayout, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Tag = '1';
            app.Button.Text = '';
            app.Button_2 = uibutton(app.GridLayout, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Tag = '2';
            app.Button_2.Text = '';
            app.Button_3 = uibutton(app.GridLayout, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Tag = '3';
            app.Button_3.Text = '';
            app.Button_4 = uibutton(app.GridLayout, 'push');
            app.Button_4.ButtonPushedFcn = createCallbackFcn(app, @Button_4Pushed, true);
            app.Button_4.Tag = '4';
            app.Button_4.Text = '';
            app.Button_5 = uibutton(app.GridLayout, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Tag = '5';
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Tag = '6';
            app.Button_6.Text = '';
            app.Button_7 = uibutton(app.GridLayout, 'push');
            app.Button_7.ButtonPushedFcn = createCallbackFcn(app, @Button_7Pushed, true);
            app.Button_7.Tag = '7';
            app.Button_7.Text = '';
            app.Button_8 = uibutton(app.GridLayout, 'push');
            app.Button_8.ButtonPushedFcn = createCallbackFcn(app, @Button_8Pushed, true);
            app.Button_8.Tag = '8';
            app.Button_8.Text = '';
            app.Button_9 = uibutton(app.GridLayout, 'push');
            app.Button_9.ButtonPushedFcn = createCallbackFcn(app, @Button_9Pushed, true);
            app.Button_9.Tag = '9';
            app.Button_9.Text = '';
            app.Switch = uiswitch(app.UIFigure, 'slider');
            app.Switch.Items = {'Light', 'Dark'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = 'Light';
            app.CRISSCROSSLabel = uilabel(app.UIFigure);
            app.CRISSCROSSLabel.Text = 'CRISS & CROSS';
            app.PLAYButton = uibutton(app.UIFigure, 'push');
            app.PLAYButton.ButtonPushedFcn = createCallbackFcn(app, @PLAYButtonPushed, true);
            app.PLAYButton.Text = 'PLAY';
            app.promptLabel = uilabel(app.UIFigure);
            app.promptLabel.Text = 'CLICK PLAY BUTTON TO PLAY';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = tic_tac_toe

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end